classdef es_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PlotPanel           matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
        TogglesPanel        matlab.ui.container.Panel
        Label_4             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label               matlab.ui.control.Label
        l_2Slider           matlab.ui.control.Slider
        l_2SliderLabel      matlab.ui.control.Label
        l_1Slider           matlab.ui.control.Slider
        l_1SliderLabel      matlab.ui.control.Label
        theta_2Slider       matlab.ui.control.Slider
        theta_2SliderLabel  matlab.ui.control.Label
        theta_1Slider       matlab.ui.control.Slider
        theta_1SliderLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        l1 = 2;
        l2 = 2;
        l3 = 1;
        theta1 = 0;
        theta2 = 0;

        T10 = NaN;
        T21 = NaN;
        T32 = NaN;
        T43 = NaN;
    end
    
    methods (Access = private)
        
        function setLimitsAndInitValues(app)
            limit = [0, 5];
            app.theta_1Slider.Limits = [-pi, pi];
            app.theta_2Slider.Limits = [-pi, pi];
            app.l_1Slider.Limits = limit;
            app.l_2Slider.Limits = limit;

            set(app.l_1Slider, 'value', app.l1);
            set(app.l_2Slider, 'value', app.l2);
            set(app.Label, 'text', num2str(app.theta1));
            set(app.Label_2, 'text', num2str(app.theta2));
            
            set(app.Label_3, 'text', num2str(app.l1));
            set(app.Label_4, 'text', num2str(app.l2));

            app.T10 = [rotz(rad2deg(app.theta1)) zeros(3, 1);
                       zeros(1, 3) 1];
            app.T43 = [roty(rad2deg(app.theta2)) zeros(3, 1);
                       zeros(1, 3) 1];
            app.T21 = [eye(3) [0; 0; app.l1];
                       zeros(1, 3) 1];
            app.T32 = [eye(3) [app.l2; 0; 0];
                       zeros(1, 3) 1];
        end

        function plotRobot(app)
            P0 = [0 0 0 1]';
            P1 = app.T10*app.T21*[0 0 0 1]';
            P2 = app.T10*app.T21*app.T32*[0 0 0 1]';
            P3 = app.T10*app.T21*app.T32*app.T43*[app.l3 0 0 1]';

            points = [P0 P1 P2 P3];

            ax = app.UIAxes;
            cla(ax);
            plot3(ax, points(1, :), points(2, :), points(3, :), '--o');

            view(ax, [-9, 30]);
            xlabel(ax, 'x');
            ylabel(ax, 'y');
            zlabel(ax, 'z');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setLimitsAndInitValues();
            app.plotRobot();
        end

        % Value changing function: theta_1Slider
        function theta_1SliderValueChanging(app, event)
            set(app.Label, 'text', num2str(event.Value));

        end

        % Value changing function: theta_2Slider
        function theta_2SliderValueChanging(app, event)
            set(app.Label_2, 'text', num2str(event.Value));
            
        end

        % Value changing function: l_1Slider
        function l_1SliderValueChanging(app, event)
            set(app.Label_3, 'text', num2str(event.Value));
            
        end

        % Value changing function: l_2Slider
        function l_2SliderValueChanging(app, event)
            set(app.Label_4, 'text', num2str(event.Value));
            
        end

        % Value changed function: theta_1Slider
        function theta_1SliderValueChanged(app, event)
            app.theta1 = event.Value;
            app.T10 = [rotz(rad2deg(app.theta1)) zeros(3, 1);
                       zeros(1, 3) 1];
            app.plotRobot();
            
        end

        % Value changed function: theta_2Slider
        function theta_2SliderValueChanged(app, event)
            app.theta2 = event.Value;
            app.T43 = [roty(rad2deg(app.theta2)) zeros(3, 1);
                       zeros(1, 3) 1];
            app.plotRobot();
        end

        % Value changed function: l_1Slider
        function l_1SliderValueChanged(app, event)
            app.l1 = event.Value;
            app.T21 = [eye(3) [0; 0; app.l1];
                       zeros(1, 3) 1];
            app.plotRobot();
        end

        % Value changed function: l_2Slider
        function l_2SliderValueChanged(app, event)
            app.l2 = event.Value;
            app.T32 = [eye(3) [app.l2; 0; 0];
                       zeros(1, 3) 1];
            app.plotRobot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TogglesPanel = uipanel(app.UIFigure);
            app.TogglesPanel.Title = 'Toggles';
            app.theta_1SliderLabel = uilabel(app.TogglesPanel);
            app.theta_1SliderLabel.Interpreter = 'tex';
            app.theta_1SliderLabel.Text = '\theta_1';
            app.theta_1Slider = uislider(app.TogglesPanel);
            app.theta_1Slider.ValueChangedFcn = createCallbackFcn(app, @theta_1SliderValueChanged, true);
            app.theta_1Slider.ValueChangingFcn = createCallbackFcn(app, @theta_1SliderValueChanging, true);
            app.theta_2SliderLabel = uilabel(app.TogglesPanel);
            app.theta_2SliderLabel.Interpreter = 'tex';
            app.theta_2SliderLabel.Text = '\theta_2';
            app.theta_2Slider = uislider(app.TogglesPanel);
            app.theta_2Slider.ValueChangedFcn = createCallbackFcn(app, @theta_2SliderValueChanged, true);
            app.theta_2Slider.ValueChangingFcn = createCallbackFcn(app, @theta_2SliderValueChanging, true);
            app.l_1SliderLabel = uilabel(app.TogglesPanel);
            app.l_1SliderLabel.Interpreter = 'tex';
            app.l_1SliderLabel.Text = 'l_1';
            app.l_1Slider = uislider(app.TogglesPanel);
            app.l_1Slider.ValueChangedFcn = createCallbackFcn(app, @l_1SliderValueChanged, true);
            app.l_1Slider.ValueChangingFcn = createCallbackFcn(app, @l_1SliderValueChanging, true);
            app.l_2SliderLabel = uilabel(app.TogglesPanel);
            app.l_2SliderLabel.Interpreter = 'tex';
            app.l_2SliderLabel.Text = 'l_2';
            app.l_2Slider = uislider(app.TogglesPanel);
            app.l_2Slider.ValueChangedFcn = createCallbackFcn(app, @l_2SliderValueChanged, true);
            app.l_2Slider.ValueChangingFcn = createCallbackFcn(app, @l_2SliderValueChanging, true);
            app.Label = uilabel(app.TogglesPanel);
            app.Label_2 = uilabel(app.TogglesPanel);
            app.Label_3 = uilabel(app.TogglesPanel);
            app.Label_4 = uilabel(app.TogglesPanel);
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = es_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end