classdef es_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PlotPanel           matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
        TooglesPanel        matlab.ui.container.Panel
        Label_3             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label               matlab.ui.control.Label
        l_2Slider           matlab.ui.control.Slider
        l_2SliderLabel      matlab.ui.control.Label
        theta_2Slider       matlab.ui.control.Slider
        theta_2SliderLabel  matlab.ui.control.Label
        theta_1Slider       matlab.ui.control.Slider
        theta_1SliderLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        theta1 = 0;
        theta2 = 0;
        h0 = 2;
        h1 = 2;
        h2 = 2;
        l2 = 3;

        b0 = NaN;
        b1 = NaN;
        b2 = NaN;

        H10 = NaN;
        H21 = NaN;
        H32 = NaN;
    end
    
    methods (Access = private)
        
        function setLimitsAndInitialValues(app)
            limit = [0, 5];
            app.theta_1Slider.Limits = [-pi, pi];
            app.theta_2Slider.Limits = [-pi, pi];
            app.l_2Slider.Limits = limit;

            set(app.theta_1Slider, 'value', app.theta1);
            set(app.theta_2Slider, 'value', app.theta2);
            set(app.l_2Slider, 'value', app.l2);

            set(app.Label, 'text', num2str(app.theta1));
            set(app.Label_2, 'text', num2str(app.theta2));
            set(app.Label_3, 'text', num2str(app.l2));

            app.b0 = [0 0 app.h0]';
            app.b1 = [0 0 app.h1]';
            app.b2 = [0 app.h2 app.l2]';

            app.H10 = [rotz(rad2deg(app.theta1)) app.b0;
                       zeros(1, 3) 1];
            app.H21 = [rotx(rad2deg(app.theta2)) app.b1;
                       zeros(1, 3) 1];
            app.H32 = [eye(3) app.b2;
                       zeros(1, 3) 1];
        end

        function plotRobot(app)
            ax = app.UIAxes;
            cla(ax);
            hold(ax, "on");
            plotFrame(eye(4), ax);
            plotFrame(app.H10, ax);
            plotFrame(app.H10*app.H21, ax);
            plotFrame(app.H10*app.H21*app.H32, ax);
            view(ax, 86, 26);
            axis(ax, [-10 10 -10 10 -10 10]);
            xlabel(ax, 'x');
            ylabel(ax, 'y');
            zlabel(ax, 'z');
            hold(ax, "off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setLimitsAndInitialValues();
            app.plotRobot();
        end

        % Value changing function: theta_1Slider
        function theta_1SliderValueChanging(app, event)
            app.theta1 = event.Value;
            set(app.Label, 'text', num2str(app.theta1));

        end

        % Value changing function: theta_2Slider
        function theta_2SliderValueChanging(app, event)
            app.theta2 = event.Value;
            set(app.Label_2, 'text', num2str(app.theta2));
            
        end

        % Value changing function: l_2Slider
        function l_2SliderValueChanging(app, event)
            app.l2 = event.Value;
            app.b2 = [0 app.h2 app.l2]';
            set(app.Label_3, 'text', num2str(app.l2));
            
        end

        % Value changed function: theta_1Slider
        function theta_1SliderValueChanged(app, event)
            app.H10 = [rotz(rad2deg(app.theta1)) app.b0;
                       zeros(1, 3) 1];
            app.plotRobot();
        end

        % Value changed function: theta_2Slider
        function theta_2SliderValueChanged(app, event)
            app.H21 = [rotx(rad2deg(app.theta2)) app.b1;
                       zeros(1, 3) 1];
            app.plotRobot();
        end

        % Value changed function: l_2Slider
        function l_2SliderValueChanged(app, event)
            app.H32 = [eye(3) app.b2;
                       zeros(1, 3) 1];
            app.plotRobot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TooglesPanel = uipanel(app.UIFigure);
            app.TooglesPanel.Title = 'Toogles';
            app.theta_1SliderLabel = uilabel(app.TooglesPanel);
            app.theta_1SliderLabel.Interpreter = 'tex';
            app.theta_1SliderLabel.Text = '\theta_1';
            app.theta_1Slider = uislider(app.TooglesPanel);
            app.theta_1Slider.ValueChangedFcn = createCallbackFcn(app, @theta_1SliderValueChanged, true);
            app.theta_1Slider.ValueChangingFcn = createCallbackFcn(app, @theta_1SliderValueChanging, true);
            app.theta_2SliderLabel = uilabel(app.TooglesPanel);
            app.theta_2SliderLabel.Interpreter = 'tex';
            app.theta_2SliderLabel.Text = '\theta_2';
            app.theta_2Slider = uislider(app.TooglesPanel);
            app.theta_2Slider.ValueChangedFcn = createCallbackFcn(app, @theta_2SliderValueChanged, true);
            app.theta_2Slider.ValueChangingFcn = createCallbackFcn(app, @theta_2SliderValueChanging, true);
            app.l_2SliderLabel = uilabel(app.TooglesPanel);
            app.l_2SliderLabel.Interpreter = 'tex';
            app.l_2SliderLabel.Text = 'l_2';
            app.l_2Slider = uislider(app.TooglesPanel);
            app.l_2Slider.ValueChangedFcn = createCallbackFcn(app, @l_2SliderValueChanged, true);
            app.l_2Slider.ValueChangingFcn = createCallbackFcn(app, @l_2SliderValueChanging, true);
            app.Label = uilabel(app.TooglesPanel);
            app.Label_2 = uilabel(app.TooglesPanel);
            app.Label_3 = uilabel(app.TooglesPanel);
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = es_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end