classdef es_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PlotPanel             matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        TogglesPanel          matlab.ui.container.Panel
        Label_5               matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        plane_angSliderLabel  matlab.ui.control.Label
        plane_angSlider       matlab.ui.control.Slider
        alphaSlider           matlab.ui.control.Slider
        alphaSliderLabel      matlab.ui.control.Label
        zSlider               matlab.ui.control.Slider
        zSliderLabel          matlab.ui.control.Label
        ySlider               matlab.ui.control.Slider
        ySliderLabel          matlab.ui.control.Label
        xSlider               matlab.ui.control.Slider
        xSliderLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        limit = 10; % Description
        alpha = NaN;
        plane_ang = pi/6;
        point_ang = NaN;

        R = NaN;
        Ts = NaN;
        
        curve = NaN;
        rotCurve = NaN;
    end
    
    methods (Access = private)
        function setLimitsAndInitValues(app)
            app.xSlider.Limits = [-app.limit, app.limit];
            app.ySlider.Limits = [-app.limit, app.limit];
            app.zSlider.Limits = [-app.limit, app.limit];
            app.alphaSlider.Limits = [-pi, pi];
            app.plane_angSlider.Limits = [-pi, pi];

            set(app.plane_angSlider, 'value', app.plane_ang);

            set(app.Label, 'text', num2str(app.xSlider.Value));
            set(app.Label_2, 'text', num2str(app.ySlider.Value));
            set(app.Label_3, 'text', num2str(app.zSlider.Value));
            set(app.Label_4, 'text', num2str(app.alphaSlider.Value));
            set(app.Label_5, 'text', num2str(app.plane_angSlider.Value));
        end

        function coeff=getPlaneCoff(app, curve)
            non_collinear_points_idxs = randi(length(curve), 3);
            non_collinear_points = curve(:, non_collinear_points_idxs);
        
            A = non_collinear_points(:, 2) - non_collinear_points(:, 1);
            B = non_collinear_points(:, 3) - non_collinear_points(:, 1);
        
            normal = cross(A, B);
            normal = -normal ./ normal(3);
            
            k = -normal'*non_collinear_points(:, 1);
            coeff = [normal; k];
        end

        function plotPlaneAndPoints(app, curve, Ts)
            [x, y] = meshgrid(-app.limit:1:app.limit);

            coeff = app.getPlaneCoff(curve);
            z = coeff(1).*x + coeff(2).*y + coeff(4);
            
            hold(app.UIAxes, "on");
            cla(app.UIAxes);
            surf(app.UIAxes, x,y,z,'FaceAlpha',0.2, 'EdgeColor','none');
            for i=1:length(Ts)
                plotFrame(Ts(:, :, i), app.UIAxes);
            end
            hold(app.UIAxes, "off");
            view(app.UIAxes, [9, 30]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setLimitsAndInitValues();

            app.alpha = app.alphaSlider.Value;
            app.plane_ang = app.plane_angSlider.Value;

            points = (app.limit/2)*[0 .82 .92 0 0 -.92 -.82 0; .66 .9 0 ...
                        -.83 -.83 0 .9 .66];

            [cv, t] = fnplt(cscvn(points));

            app.curve = [cv; zeros(1, length(cv))];

            app.Ts = zeros(4, 4, length(app.curve));

            app.rotCurve = rotx(rad2deg(app.plane_ang))*app.curve;

            app.point_ang = app.plane_ang - app.alpha;

            transl = [app.xSlider.Value; app.ySlider.Value; app.zSlider.Value];

            for i=1:length(app.Ts)
                app.Ts(1:3, 1:3, i) = rotx(rad2deg(app.point_ang));
                app.Ts(:, 4, i) = [app.rotCurve(:, i) + transl; 1];
            end

            app.plotPlaneAndPoints(app.rotCurve, app.Ts);

        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            set(app.Label, 'text', num2str(event.Value));
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            set(app.Label_2, 'text', num2str(event.Value));
        end

        % Value changing function: zSlider
        function zSliderValueChanging(app, event)
            set(app.Label_3, 'text', num2str(event.Value));
        end

        % Value changing function: alphaSlider
        function alphaSliderValueChanging(app, event)
            set(app.Label_4, 'text', num2str(event.Value));
        end

        % Value changing function: plane_angSlider
        function plane_angSliderValueChanging(app, event)
            set(app.Label_5, 'text', num2str(event.Value));            
        end

        % Value changed function: plane_angSlider
        function plane_angSliderValueChanged(app, event)
            app.plane_ang = event.Value;
            app.rotCurve = rotx(rad2deg(app.plane_ang))*app.curve;
            
            app.point_ang = app.plane_ang - app.alpha;

            for i=1:length(app.Ts)
                app.Ts(1:3, 1:3, i) = rotx(rad2deg(app.point_ang));
                app.Ts(:, 4, i) = [app.rotCurve(:, i); 1];
            end
            app.plotPlaneAndPoints(app.rotCurve, app.Ts);
            
        end

        % Value changed function: alphaSlider
        function alphaSliderValueChanged(app, event)
            app.alpha = event.Value;

            app.point_ang = app.plane_ang - app.alpha;

            for i=1:length(app.Ts)
                app.Ts(1:3, 1:3, i) = rotx(rad2deg(app.point_ang));
                app.Ts(:, 4, i) = [app.rotCurve(:, i); 1];
            end
            app.plotPlaneAndPoints(app.rotCurve, app.Ts);
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            transl = [app.xSlider.Value; app.ySlider.Value; app.zSlider.Value];

            tmp_curve = app.rotCurve + repmat(transl, 1, length(app.rotCurve));

            for i=1:length(app.Ts)
                app.Ts(1:3, 1:3, i) = rotx(rad2deg(app.point_ang));
                app.Ts(:, 4, i) = [tmp_curve(:, i); 1];
            end

            app.plotPlaneAndPoints(tmp_curve, app.Ts);
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            transl = [app.xSlider.Value; app.ySlider.Value; app.zSlider.Value];

            tmp_curve = app.rotCurve + repmat(transl, 1, length(app.rotCurve));

            for i=1:length(app.Ts)
                app.Ts(1:3, 1:3, i) = rotx(rad2deg(app.point_ang));
                app.Ts(:, 4, i) = [tmp_curve(:, i); 1];
            end

            app.plotPlaneAndPoints(tmp_curve, app.Ts);
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
            transl = [app.xSlider.Value; app.ySlider.Value; app.zSlider.Value];

            tmp_curve = app.rotCurve + repmat(transl, 1, length(app.rotCurve));

            for i=1:length(app.Ts)
                app.Ts(1:3, 1:3, i) = rotx(rad2deg(app.point_ang));
                app.Ts(:, 4, i) = [tmp_curve(:, i); 1];
            end

            app.plotPlaneAndPoints(tmp_curve, app.Ts);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TogglesPanel = uipanel(app.UIFigure);
            app.TogglesPanel.Title = 'Toggles';
            app.xSliderLabel = uilabel(app.TogglesPanel);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.TogglesPanel);
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.ySliderLabel = uilabel(app.TogglesPanel);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.TogglesPanel);
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.zSliderLabel = uilabel(app.TogglesPanel);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.TogglesPanel);
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.alphaSliderLabel = uilabel(app.TogglesPanel);
            app.alphaSliderLabel.Interpreter = 'latex';
            app.alphaSliderLabel.Text = '\alpha';
            app.alphaSlider = uislider(app.TogglesPanel);
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @alphaSliderValueChanged, true);
            app.alphaSlider.ValueChangingFcn = createCallbackFcn(app, @alphaSliderValueChanging, true);
            app.plane_angSlider = uislider(app.TogglesPanel);
            app.plane_angSlider.ValueChangedFcn = createCallbackFcn(app, @plane_angSliderValueChanged, true);
            app.plane_angSlider.ValueChangingFcn = createCallbackFcn(app, @plane_angSliderValueChanging, true);
            app.plane_angSliderLabel = uilabel(app.TogglesPanel);
            app.plane_angSliderLabel.Text = 'plane_ang';
            app.Label = uilabel(app.TogglesPanel);
            app.Label_2 = uilabel(app.TogglesPanel);
            app.Label_3 = uilabel(app.TogglesPanel);
            app.Label_4 = uilabel(app.TogglesPanel);
            app.Label_5 = uilabel(app.TogglesPanel);
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = es_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end