classdef es_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        Panel           matlab.ui.container.Panel
        ySlider         matlab.ui.control.Slider
        ySliderLabel    matlab.ui.control.Label
        xSlider         matlab.ui.control.Slider
        xSliderLabel    matlab.ui.control.Label
        RightPanel      matlab.ui.container.Panel
        TabGroup        matlab.ui.container.TabGroup
        PlotTab         matlab.ui.container.Tab
        UIAxes          matlab.ui.control.UIAxes
        forceVolumeTab  matlab.ui.container.Tab
        UIAxes2         matlab.ui.control.UIAxes
        speedVolumeTab  matlab.ui.container.Tab
        UIAxes3         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        L=10 % Description
        r=2 % Description
    end
    
    methods (Access = private)
        
        function fvec = fk(app, q1, q2, r, L) % forward kinematics
            l1 = q1*r;
            l2 = q2*r;
            x = (L^2 + l1^2 - l2^2)/(2*L);
            fvec = [x;
                    -sqrt((l1^2 - x^2))
                   ];
        end

        function ivec = ik(app, x, y, r, L) % inverse kinematics
            ivec = [sqrt((x^2 + y^2))/r;
                    sqrt(((L - x)^2 + y^2))/r
                   ];
        end

        function forceVolume(app, dim, r, L) % function to plot the volume
            x = 0:L/(dim-1):L;
            y = -L:L/(dim-1):0;

            [v, s] = meshgrid(x, y);
    
            tuples = [v(:) s(:)];

            volumes = -1*ones(length(tuples), 1);

            for i=1:length(tuples)
                vec = tuples(i, :);
                try
                    q = app.ik(vec(1), vec(2), r, L);
                    J = app.jac(q(1), q(2), r, L);
                    volumes(i) = sqrt(det(J*J'));
                catch
                    volumes(i) = -1;
                end
            end
            volumes = reshape(volumes, [], cast(sqrt(length(tuples)), "uint32"));

            ax = app.UIAxes2;
            cla(ax);
            surf(ax, v, s, volumes);
            xlabel(ax, 'x');
            ylabel(ax, 'y');
            zlabel(ax, 'volume');
        end

        function speedVolume(app, dim, r, L) % function to plot the volume
            x = 0:L/(dim-1):L;
            y = -L:L/(dim-1):0;

            [v, s] = meshgrid(x, y);
    
            tuples = [v(:) s(:)];

            volumes = -1*ones(length(tuples), 1);

            for i=1:length(tuples)
                vec = tuples(i, :);
                try
                    q = app.ik(vec(1), vec(2), r, L);
                    J = app.jac(q(1), q(2), r, L);
                    volumes(i) = sqrt(det(inv(J*J')));
                catch
                    volumes(i) = -1;
                end
            end
            volumes = reshape(volumes, [], cast(sqrt(length(tuples)), "uint32"));

            ax = app.UIAxes3;
            cla(ax);
            surf(ax, v, s, volumes);
            xlabel(ax, 'x');
            ylabel(ax, 'y');
            zlabel(ax, 'volume');
        end

        function mjac = jac(app, q1, q2, r, L) % jacobian calculation
            q = sym('q_%d',[1 2]);
    
            l1 = q(1)*r;
            l2 = q(2)*r;
            x = (L^2 + l1^2 - l2^2)/(2*L);
            fvec = [x;
                    -sqrt((l1^2 - x^2))
                   ];
            
            mjac = double(subs(jacobian(fvec, q), q, [q1, q2]));
        end

        function ellipse=getElliseFromJacobian(app, C, J)

            [vec, val] = eig(J);

            theta = atan2(vec(2,1), vec(1,1));

            eigs = diag(val);

            eigs = eigs./norm(eigs); % normalize in order to make the ellipse compatible

            scale = 1.5;

            % Get the lengths of the semi-major and semi-minor axes
            a = scale*eigs(1);
            b = scale*eigs(2);

            % Define the ellipse
            t = linspace(0, 2*pi, 100);
            ellipse_x = a * cos(t);
            ellipse_y = b * sin(t);

            % Rotation matrix
            R = [cos(theta) -sin(theta); sin(theta) cos(theta)];

            % Rotate the ellipse to the correct orientation
            ellipse = C + R * [ellipse_x; ellipse_y];
        end

        function plotEllipse(app, q1, q2, r, L, posx, posy)
            C = [posx; posy]; % center of the ellipse in reality in the gui is the point on which we calculate the inverse kinematics

            J = app.jac(q1, q2, r, L);

            M = J*J'; % speed manipulability (in order to have the quadratic form)
            inv_M = inv(M); % force manipulability (in order to have the quadratic form)

            speed_ell = app.getElliseFromJacobian(C, M);
            force_ell = app.getElliseFromJacobian(C, inv_M);
            
            ax = app.UIAxes;
            hold(ax,'on');
            cla(ax);
            ax.XLim = [0 L];
            ax.YLim = [-L 0];
            plot(ax, [0, posx], [0, posy], "-.", 'Color', [0.5 0 0.8]);
            plot(ax, [L, posx], [0, posy], "-.", 'Color', [0.5 0 0.8]);
            % plot(ax, [C(1), C(1) + val(1, 1)*cos(theta1)], [C(2), C(2) + val(2, 2)*sin(theta1)])
            % plot(ax, [C(1), C(1) + val(1, 1)*cos(theta2)], [C(2), C(2) + val(2, 2)*sin(theta2)])
            plot(ax, speed_ell(1, :), speed_ell(2, :), "Color", 'red');
            plot(ax, force_ell(1, :), force_ell(2, :), "Color", 'blue');
            hold(ax,'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xSlider.Value = 5;
            app.ySlider.Value = -5;
            q = app.ik(app.xSlider.Value, app.ySlider.Value, app.r, app.L);
            app.plotEllipse(q(1), q(2), app.r, app.L, app.xSlider.Value, app.ySlider.Value);
            app.forceVolume(10, app.r, app.L);
            app.speedVolume(10, app.r, app.L);
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            q = app.ik(app.xSlider.Value, app.ySlider.Value, app.r, app.L);
            app.plotEllipse(q(1), q(2), app.r, app.L, app.xSlider.Value, app.ySlider.Value);
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            q = app.ik(app.xSlider.Value, app.ySlider.Value, app.r, app.L);
            app.plotEllipse(q(1), q(2), app.r, app.L, app.xSlider.Value, app.ySlider.Value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {355, 355};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {237, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = 'Panel';
            app.xSliderLabel = uilabel(app.Panel);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.Panel);
            app.xSlider.Limits = [1e-06 10];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.Value = 5;
            app.ySliderLabel = uilabel(app.Panel);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.Panel);
            app.ySlider.Limits = [-10 1e-06];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.Value = -5;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, '2D rope robot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.forceVolumeTab = uitab(app.TabGroup);
            app.forceVolumeTab.Title = 'forceVolume';
            app.UIAxes2 = uiaxes(app.forceVolumeTab);
            title(app.UIAxes2, 'volume plot')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.speedVolumeTab = uitab(app.TabGroup);
            app.speedVolumeTab.Title = 'speedVolume';
            app.UIAxes3 = uiaxes(app.speedVolumeTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = es_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end