classdef es_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        KinematicsTab  matlab.ui.container.Tab
        UIAxes         matlab.ui.control.UIAxes
        ErrorPlotTab   matlab.ui.container.Tab
        UIAxes_2       matlab.ui.control.UIAxes
    end

   properties (Access = private)
        m1 = 0.5;
        m2 = 1;

        I1 = 2;
        I2 = 1;

        a1 = 3;
        a2 = 3;

        q0 = [pi/8, pi/8];
        dq0 = zeros(2, 1);

        dt = 0.01;

        error = NaN;

        qs = NaN;
        dqs = NaN;
    end
    
    methods (Access = private)
        function setLimits(app)
            lim = app.a1 + app.a2;
            app.curveinitxSlider.Limits = [-lim, lim];
            app.curveinitySlider.Limits = [-lim, lim];
            set(app.curveinitxSlider, 'value', 2);
            set(app.curveinitxSlider, 'value', 2);
        end

        function M=getM(app, m1, m2)
            M = [m1 0 m2 0;
                 0 m1 0 m2;
                 0 0 m2 0;
                 0 0 0 m2
                ];
        end
        function I=getI(app, I1, I2)
            I = [I1 0;
                 I1 I2
                ];
        end
        function A=getA(app, th1, th2, a1, a2)
            A=[-(a1/2)*sin(th1) (a1/2)*cos(th1) -(a1/2)*sin(th1) (a1/2)*cos(th1);
                0 0 -(a2/2)*sin(th1 + th2) (a2/2)*cos(th1 + th2)
              ];
        end

        function D=getD(app, th1, th2, a1, a2)
            D = [-(a1/2)*sin(th1) 0;
                 -(a1/2)*cos(th1) 0;
                 (-a1*sin(th1) -(a2/2)*sin(th1 + th2)) -(a2/2)*sin(th1 + th2);
                 (a1*cos(th1) +(a2/2)*cos(th1 + th2)) (a2/2)*cos(th1 + th2);
                ];
        end
        
        function E=getE(app, th1, th2, dth1, dth2, a1, a2)
            E = [-(a1/2)*cos(th1)*dth1 0;
                 -(a1/2)*sin(th1)*dth1 0;
                 (-a1*cos(th1)*dth1 - (a2/2)*cos(th1+th2)*dth1) (-a2*cos(th1+th2)*dth1 -(a2/2)*cos(th1+th2)*dth2);
                 (-a1*sin(th1)*dth1 - (a2/2)*sin(th1+th2)*dth1) (-a2*sin(th1+th2)*dth1 -(a2/2)*sin(th1+th2)*dth2)
                ];
        end
        
        function ddq=robotODE(app, q, dq, tau, a1, a2)
            F = [1 -1;
                 0  1;
                ];
            M = app.getM(app.m1, app.m2);
            I = app.getI(app.I1, app.I2);
            A = app.getA(q(1), q(2), a1, a2);
            D = app.getD(q(1), q(2), a1, a2);
            E = app.getE(q(1), q(2), dq(1), dq(2), a1, a2);
        
            B = inv(F)*(A*M*D + I);
            C = inv(F)*A*M*E;
            ddq = inv(B)*(tau - C*dq); % for now I'll not consider any gravitational force
        end

        function [qr, dqr]=integrate(app, q, dq, tau, a1, a2)
            ddq = app.robotODE(q, dq, tau, a1, a2);
            dqr = dq + app.dt*ddq;
            qr = q + app.dt*dqr;
        end

        function [p1, p2]=fk(app, th1, th2, a1, a2)
            p1 = a1*[cos(th1); sin(th1)];
            p2 = p1 + a2*[cos(th1 + th2); sin(th1 + th2)];
        end

        function angles = ik(app, pos, a1, a2)
            
            num_2 = pos(1)^2 + pos(2)^2 - a1^2 - a2^2;
            ang_2 = acos(num_2 / (2*a1*a2));
            
            num_1 = a2 * sin(ang_2);
            denum_1 = a1 + a2 * cos(ang_2);
            ang_1 = atan2(pos(2), pos(1)) - atan2(num_1, denum_1);
         
            angles = [ang_1 ang_2];
        end

        function trajectory=create_trajectory(app, p2)
            %tx = rand(1,app.curve_points)*l_x;
            %ty = rand(1,app.curve_points)*l_y;

            tx = ones(1,4)*p2(1) +  [0 0 1 1];
            ty = ones(1,4)*p2(2) + [0 1 0 0];
            xy = [tx; ty];

            [points, t] = fnplt(cscvn(xy));
            sub_columns = 1:2:length(points(1, :));

            trajectory = points(:, sub_columns);
        end

        function trajectory=create_lin_trajectory(app, n_points)
            %tx = rand(1,app.curve_points)*l_x;
            %ty = rand(1,app.curve_points)*l_y;

            [p1, p2] = app.fk(app.q0(1), app.q0(2), app.a1, app.a2);
            
            t = linspace(p2(1)/4, p2(1)/2, n_points);

            m = (p2(2)/2 - (p2(2)/4))/(p2(1)/2 - (p2(1)/4));
            q = p2(2) - m*t(1);
            trajectory = [t; m*t + ones(1, n_points)*q];
        end

        function plotRobotAndTrajectory(app, p0, q, trajectory, a1, a2)
            ax = app.UIAxes;
            hold(ax,'on');
            cla(ax);
            [p1, p2] = app.fk(q(1), q(2), a1, a2);
            plot(app.UIAxes, trajectory(1, :), trajectory(2, :), '--');
            plot(app.UIAxes, [p0(1) p1(1)], [p0(2) p1(2)], '-o', 'Color',[0, 0, 1]);
            plot(app.UIAxes, [p1(1) p2(1)], [p1(2) p2(2)], '-o', 'Color',[0, 0, 1]);
            plot(app.UIAxes, trajectory(1, length(trajectory)), trajectory(2, length(trajectory)), '-o');
            plot(app.UIAxes, trajectory(1, 1), trajectory(2, 1), '-o');
            hold(ax, 'off');
        end

        function err = wrap(app, angle)
            err = atan2(sin(angle), cos(angle));
        end

        function plotError(app, error)
            ax = app.UIAxes_2;
            hold(ax,'on');
            cla(ax);
            plot(app.UIAxes_2, error(1, :), '--', "Color", 'red');
            plot(app.UIAxes_2, error(2, :), '--', "Color", 'blue');
            hold(ax, "off");
        end

        function simulate(app, duration)
        
            steps = cast(duration/app.dt, "int32");

            app.qs = -1*ones(2, steps);
            app.dqs = -1*ones(2, steps);

            trajectory = app.create_lin_trajectory(50);

            app.q0 = app.ik([trajectory(1, 1), trajectory(2, 1)]', app.a1, app.a2);

            app.qs(:, 1) = app.q0;

            app.dqs(:, 1) = app.dq0;

            % trajectory = app.create_trajectory(NaN, NaN);

            trajectory_steps = duration/length(trajectory);

            pid_x = PID(0.9, 0, 0.01); % 0.1, 0.01, 0.01
            pid_y = PID(0.9, 0, 0.01);

            app.plotRobotAndTrajectory([0; 0], app.q0, trajectory, app.a1, app.a2);

            app.error = zeros(2, length(steps));

            target_idx = 2;
            
            for i=1:steps-1
                tic;
                [p1, p2] = app.fk(app.qs(1, i), app.qs(2, i), app.a1, app.a2); % p2 is the end effector position

                target_pos = trajectory(:, target_idx); % modificare in modo da fare si che abbia tanti punti quanti sono gli steps
                
                target_angle = app.ik(target_pos, app.a1, app.a2);
                curr_angle = app.ik(p2, app.a1, app.a2);

                err = target_angle - curr_angle;
                
                err(1) = app.wrap(err(1));
                err(2) = app.wrap(err(2));

                app.error(:, i) = err;

                if (i*app.dt > (target_idx-1)*trajectory_steps) && (target_idx < length(trajectory))
                    target_idx =  target_idx + 1;
                elseif i*app.dt >= duration
                    break;
                end

                % if (err < 0.001) & (target_idx < length(trajectory)) % do it better
                %     target_idx = target_idx + 1;
                % elseif (err < 0.001) & (target_idx == length(trajectory))
                %     break;
                % end

                tau = [pid_x.compute(err(1), app.dt);
                       pid_y.compute(err(2), app.dt)
                      ];

                [q, dq] = app.integrate(app.qs(:, i), app.dqs(:, i), tau, app.a1, app.a2);

                app.qs(:, i+1) = q;
                app.dqs(:, i+1) = dq;

                calculation_time = toc;

                app.plotRobotAndTrajectory([0; 0], q, trajectory, app.a1, app.a2);

                % fprintf('iter: %d, target_idx: %d\n', i, target_idx);

                title(app.UIAxes, ['Time: ' num2str(i*app.dt)]);
                
                pause(app.dt - calculation_time);
            end

            app.plotError(app.error);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.simulate(5);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.KinematicsTab = uitab(app.TabGroup);
            app.KinematicsTab.Title = 'Kinematics';
            app.UIAxes = uiaxes(app.KinematicsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ErrorPlotTab = uitab(app.TabGroup);
            app.ErrorPlotTab.Title = 'Error-Plot';
            app.UIAxes_2 = uiaxes(app.ErrorPlotTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = es_7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end