classdef es_7_prova < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        m1 = 0.5;
        m2 = 1;

        I1 = 2;
        I2 = 1;

        a1 = 2;
        a2 = 2;

        q0 = [  0;
                pi/2
             ];
        dq0 = zeros(2, 1);

        dt = 0.01;

        qs = NaN;
        dqs = NaN;
    end
    
    methods (Access = private)
        function M=getM(app, m1, m2)
            M = [m1 0 m2 0;
                 0 m1 0 m2;
                 0 0 m2 0;
                 0 0 0 m2
                ];
        end
        function I=getI(app, I1, I2)
            I = [I1 0;
                 I1 I2
                ];
        end
        function A=getA(app, th1, th2, a1, a2)
            A=[-(a1/2)*sin(th1) (a1/2)*cos(th1) -(a1/2)*sin(th1) (a1/2)*cos(th1);
                0 0 -(a2/2)*sin(th1 + th2) (a2/2)*cos(th1 + th2)
              ];
        end

        function D=getD(app, th1, th2, a1, a2)
            D = [-(a1/2)*sin(th1) 0;
                 -(a1/2)*cos(th1) 0;
                 (-a1*sin(th1) -(a2/2)*sin(th1 + th2)) -(a2/2)*sin(th1 + th2);
                 (a1*cos(th1) +(a2/2)*cos(th1 + th2)) (a2/2)*cos(th1 + th2);
                ];
        end
        
        function E=getE(app, th1, th2, dth1, dth2, a1, a2)
            E = [-(a1/2)*cos(th1)*dth1 0;
                 -(a1/2)*sin(th1)*dth1 0;
                 (-a1*cos(th1)*dth1 - (a2/2)*cos(th1+th2)*dth1) (-a2*cos(th1+th2)*dth1 -(a2/2)*cos(th1+th2)*dth2);
                 (-a1*sin(th1)*dth1 - (a2/2)*sin(th1+th2)*dth1) (-a2*sin(th1+th2)*dth1 -(a2/2)*sin(th1+th2)*dth2)
                ];
        end
        
        function ddq=robotODE(app, q, dq, tau, a1, a2)
            F = [1 -1;
                 0  1;
                ];
            M = app.getM(app.m1, app.m2);
            I = app.getI(app.I1, app.I2);
            A = app.getA(q(1), q(2), a1, a2);
            D = app.getD(q(1), q(2), a1, a2);
            E = app.getE(q(1), q(2), dq(1), dq(2), a1, a2);
        
            B = inv(F)*(A*M*D + I);
            C = inv(F)*A*M*E;
            ddq = inv(B)*(tau - C*dq); % for now I'll not consider any gravitational force
        end

        function [qr, dqr]=integrate(app, q, dq, tau, a1, a2)
            ddq = app.robotODE(q, dq, tau, a1, a2);
            dqr = dq + app.dt*ddq;
            qr = q + app.dt*dqr;
        end

        function tau=fwODE(app, q, dq, ddq, a1, a2)
            F = [1 -1;
                 0  1;
                ];
            M = app.getM(app.m1, app.m2);
            I = app.getI(app.I1, app.I2);
            A = app.getA(q(1), q(2), a1, a2);
            D = app.getD(q(1), q(2), a1, a2);
            E = app.getE(q(1), q(2), dq(1), dq(2), a1, a2);
        
            B = inv(F)*(A*M*D + I);
            C = inv(F)*A*M*E;
            tau = (B*ddq) + (C*dq); % for now I'll not consider any gravitational force
        end

        function [p1, p2]=fk(app, th1, th2, a1, a2)
            p1 = a1*[cos(th1); sin(th1)];
            p2 = p1 + a2*[cos(th1 + th2); sin(th1 + th2)];
        end

        function angles = ik(app, pos, a1, a2)
            
            num_2 = pos(1)^2 + pos(2)^2 - a1^2 - a2^2;
            ang_2 = acos(num_2 / (2*a1*a2));
            
            num_1 = a2 * sin(ang_2);
            denum_1 = a1 + a2 * cos(ang_2);
            ang_1 = atan2(pos(2), pos(1)) - atan2(num_1, denum_1);
         
            angles = [ang_1 ang_2];
        end

        function trajectory=create_trajectory(app, l_x, l_y)
            %tx = rand(1,app.curve_points)*l_x;
            %ty = rand(1,app.curve_points)*l_y;

            [p1, p2] = app.fk(app.q0(1), app.q0(2), app.a1, app.a2);

            tx = ones(1,4)*p2(1) - [1.5, 1, 0.5, 0];
            ty = ones(1,4)*p2(2) - [1, 0, 0.5, 0];
            xy = [tx; ty];

            [points, t] = fnplt(cscvn(xy));
            sub_columns = 1:2:length(points(1, :));

            trajectory = points(:, sub_columns);
        end

        function trajectory=create_lin_trajectory(app, n_points)
            %tx = rand(1,app.curve_points)*l_x;
            %ty = rand(1,app.curve_points)*l_y;

            [p1, p2] = app.fk(app.q0(1), app.q0(2), app.a1, app.a2);
            
            t = linspace(p2(1)/4, p2(1)/2, n_points);

            m = (p2(2)/2 - (p2(2)/4))/(p2(1)/2 - (p2(1)/4));
            q = p2(2) - m*t(1);
            trajectory = [t; m*t + ones(1, n_points)*q];
        end

        function torques=compute_nominal_torques(app, trajectory)

            torques = -1*ones(2, length(trajectory) - 1);

            tq0  = [0 0]';
            tdq0 = [0 0]';

            for i=1:length(trajectory) - 1
                tq = app.ik(trajectory(:, i), app.a1, app.a2)';
                dq = tq - tq0;
                ddq = dq - tdq0;

                disp(app.fwODE(tq, dq, ddq, app.a1, app.a2))

                torques(:, i) = app.fwODE(tq, dq, ddq, app.a1, app.a2);
                tq0 = tq;
                tdq0 = dq;
            end
            
        end

        function plotRobotAndTrajectory(app, p0, q, trajectory, a1, a2)
            ax = app.UIAxes;
            hold(ax,'on');
            cla(ax);
            [p1, p2] = app.fk(q(1), q(2), a1, a2);
            plot(app.UIAxes, trajectory(1, :), trajectory(2, :), '--');
            plot(app.UIAxes, [p0(1) p1(1)], [p0(2) p1(2)], '-o', 'Color',[0, 0, 1]);
            plot(app.UIAxes, [p1(1) p2(1)], [p1(2) p2(2)], '-o', 'Color',[0, 0, 1]);
            plot(app.UIAxes, trajectory(1, length(trajectory)), trajectory(2, length(trajectory)), '-o');
            plot(app.UIAxes, trajectory(1, 1), trajectory(2, 1), '-o');
            hold(ax, 'off');
        end

        function simulate(app, duration)
        
            steps = cast(duration/app.dt, "int32");

            app.qs = -1*ones(2, steps);
            app.dqs = -1*ones(2, steps);

            app.dqs(:, 1) = app.dq0;

            trajectory = app.create_lin_trajectory(steps);

            nominal_torques =  app.compute_nominal_torques(trajectory);

            app.qs(:, 1) = app.ik(trajectory(:, 1), app.a1, app.a2);

            pid_x = PID(1, 0.1, 0); % 0.1, 0.01, 0.01
            pid_y = PID(1, 0.1, 0);

            for i=1:steps-1
                [p1, p2] = app.fk(app.qs(1, i), app.qs(2, i), app.a1, app.a2); % p2 is the end effector position
                
                set_point = app.ik(trajectory(:, i), app.a1, app.a2);

                err = set_point - app.qs(:, i);

                tau = nominal_torques(:, i) + [pid_x.compute(err(1), app.dt) pid_y.compute(err(2), app.dt)]';

                [q, dq] = app.integrate(app.qs(:, i), app.dqs(:, i), tau, app.a1, app.a2);

                if norm(q - trajectory(length(trajectory))) < 0.1
                    break
                end

                app.qs(:, i+1) = q;
                app.dqs(:, i+1) = dq;

                app.plotRobotAndTrajectory([0; 0], q, trajectory, app.a1, app.a2);

                fprintf('iter: %d\n', i);

                title(app.UIAxes, ['Time: ' num2str(i*app.dt)]);

                pause(0.01);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simulate(5)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = es_7_prova

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end